classdef RFStimGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RFStimulusMapper       matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        ScreenNumberField      matlab.ui.control.EditField
        ScreenNumberLabel      matlab.ui.control.Label
        GridButtonGroup        matlab.ui.container.ButtonGroup
        RadioButtonFullscreen  matlab.ui.control.RadioButton
        RadioButton2x2         matlab.ui.control.RadioButton
        RadioButton3x3         matlab.ui.control.RadioButton
        ViewingDistanceField   matlab.ui.control.EditField
        ViewingDistanceLabel   matlab.ui.control.Label
        TileDegField           matlab.ui.control.EditField
        TileDegLabel           matlab.ui.control.Label
        ISIField               matlab.ui.control.EditField
        ISILabel               matlab.ui.control.Label
        NCycleField            matlab.ui.control.EditField
        NCycleLabel            matlab.ui.control.Label
        DurInitGrayField       matlab.ui.control.EditField
        DurInitGrayLabel       matlab.ui.control.Label
        GammaLabel             matlab.ui.control.Label
        LoadGammaButton        matlab.ui.control.Button
        MovieLabel             matlab.ui.control.Label
        LoadMovieButton        matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        movData                 % hold loaded movie matrix
        selectedRegions = {}    % cell array of region codes
        gammaTable              % hold loaded or default gamma curve
        screenXpx       % screen width in pixels
        screenYpx       % screen height in pixels
        screenXcm       % screen width in centimeters
        screenYcm       % screen height in centimeters
    end

    methods (Access = private)

        function loadMovieFromFile(app, filepath)
            S    = load(filepath);
            vars = fieldnames(S);
            is3D = cellfun(@(v) isnumeric(S.(v)) && ndims(S.(v))==3, vars);
            good = vars(is3D);
            if isempty(good)
                uialert(app.RFStimulusMapper, ...
                    sprintf('No 3-D variable in %s.', filepath), ...
                    'Load error');
                return;
            end
            name = good{1};
            app.movData         = S.(name);
            app.MovieLabel.Text = '✔';
            app.drawPatches();
        end

        function loadDefaultMovie(app)
            fullAppPath = mfilename('fullpath');
            appFolder   = fileparts(fullAppPath);
            D = dir(fullfile(appFolder,'*rfmovie*.mat'));
            if isempty(D)
                error('No default *rfmovie*.mat in %s.', appFolder);
            end
            app.loadMovieFromFile(fullfile(appFolder, D(1).name));
        end

        function loadGammaFromFile(app, filepath)
            % Load the .mat and find NormGamTab
            tmp = load(filepath);
            if ~isfield(tmp,'NormGamTab')
                uialert(app.RFStimulusMapper, ...
                    sprintf('File %s has no variable NormGamTab.', filepath), ...
                    'Load error');
                return;
            end
            app.gammaTable        = tmp.NormGamTab;
            app.GammaLabel.Text   = '✔';
            app.GammaLabel.FontColor = [0 .6 0];
        end

        function loadDefaultGamma(app)
            % Find any *NormGamTab*.mat in the app folder
            fullAppPath = mfilename('fullpath');
            appFolder   = fileparts(fullAppPath);
            D = dir(fullfile(appFolder,'*NormGamTab*.mat'));
            if isempty(D)
                error('No default *NormGamTab*.mat in %s.', appFolder);
            end
            % Auto‐load the first match
            app.loadGammaFromFile(fullfile(appFolder, D(1).name));
        end

        function drawPatches(app)
            cla(app.UIAxes)
            app.selectedRegions = {};
            app.UIAxes.Visible = 'on';
            hold(app.UIAxes,'on')
            if app.RadioButtonFullscreen.Value
                % Single centered fullscreen patch placeholder
                p = patch(app.UIAxes, ...
                    'XData',[0,1,1,0], ...
                    'YData',[0,0,1,1], ...
                    'FaceColor',[1 1 1], ...
                    'FaceAlpha', 0, ...
                    'EdgeColor',[.4 .4 .4], ...
                    'LineWidth',1, ...
                    'HitTest','on', ...
                    'PickableParts','all', ...
                    'UserData','full');
                p.ButtonDownFcn = @(src,evt) app.onRegionClick(src,evt);
            else
                % Draw grid patches
                if app.RadioButton2x2.Value
                    nR=2; nC=2; codes={'tl','tr';'bl','br'};
                else
                    nR=3; nC=3; codes={'nw','n','ne';'w','center','e';'sw','s','se'};
                end
                for r = 1:nR
                    for c = 1:nC
                        pos = [(c-1)/nC, (nR-r)/nR, 1/nC, 1/nR];
                        p = patch(app.UIAxes, ...
                            'XData',[pos(1),pos(1)+pos(3),pos(1)+pos(3),pos(1)], ...
                            'YData',[pos(2),pos(2),pos(2)+pos(4),pos(2)+pos(4)], ...
                            'FaceColor',[1 1 1], ...
                            'FaceAlpha', 0, ...
                            'EdgeColor',[.4 .4 .4], ...
                            'LineWidth',1, ...
                            'HitTest','on', ...
                            'PickableParts','all', ...
                            'UserData',codes{r,c});
                        p.ButtonDownFcn = @(src,evt) app.onRegionClick(src,evt);
                    end
                end
            end
            hold(app.UIAxes,'off')
        end

        % Handle clicks on individual patches
        function onRegionClick(app, src, ~)
            code = src.UserData;
            if src.FaceAlpha == 0
                src.FaceColor = [1 .6 .6]; src.FaceAlpha = 1;
                app.selectedRegions{end+1} = code;
            else
                src.FaceAlpha = 0; src.FaceColor = [1 1 1];
                app.selectedRegions = setdiff(app.selectedRegions, code, 'stable');
            end
            app.updateSubdivisionPreview();
        end

        function updateSubdivisionPreview(app, degOverride, distOverride)
            % Clear previous previews
            delete(findall(app.UIAxes,'Tag','PreviewLine'));
            drawnow;

            % Parse inputs
            if nargin>1 && ~isempty(degOverride), rawDeg = degOverride; else rawDeg = app.TileDegField.Value; end
            if nargin>2 && ~isempty(distOverride), rawDist = distOverride; else rawDist = app.ViewingDistanceField.Value; end
            tileDeg = str2double(rawDeg); D = str2double(rawDist);
            if any(isnan([tileDeg D])) || tileDeg<=0 || D<=0, return; end

            % Calculate tile size in normalized units
            tileCm = 2 * D * tan(deg2rad(tileDeg/2));
            ppi = get(0,'ScreenPixelsPerInch'); pxPerCm = ppi/2.54;
            tilePx = round(tileCm * pxPerCm);
            tileNormX = tilePx / app.screenXpx;
            tileNormY = tilePx / app.screenYpx;

            hold(app.UIAxes,'on')
            if app.RadioButtonFullscreen.Value
                % Draw a single centered tile outline
                x1 = 0.5 - tileNormX/2;
                y1 = 0.5 - tileNormY/2;
                rectangle(app.UIAxes, 'Position',[x1, y1, tileNormX, tileNormY], ...
                    'EdgeColor',[1 .6 .6], 'LineWidth',2, 'Tag','PreviewLine');
                hold(app.UIAxes,'off');
                return;
            end

            % For grid modes, subdivide selected region(s)
            allP = findobj(app.UIAxes, 'Type', 'Patch');
            if isempty(allP), hold(app.UIAxes,'off'); return; end
            ud = get(allP,'UserData'); if ~iscell(ud), ud={ud}; end
            selIdx = ismember(ud, app.selectedRegions);
            selP = allP(selIdx);
            if isempty(selP), hold(app.UIAxes,'off'); return; end

            % Calculate bounds of selection
            xs=[]; ys=[];
            for pH = selP(:).'
                V = pH.Vertices;
                xs=[xs; V(:,1)]; ys=[ys; V(:,2)];
            end
            x1=min(xs); x2=max(xs); y1=min(ys); y2=max(ys);

            % Draw subdivision lines
            nX = floor((x2-x1)/tileNormX);
            for ix=1:nX-1
                xv = x1 + ix*tileNormX;
                line(app.UIAxes, [xv xv], [y1 y2], 'LineStyle','--', 'Tag','PreviewLine', ...
                    'HitTest','off','PickableParts','none');
            end
            nY = floor((y2-y1)/tileNormY);
            for iy=1:nY-1
                yv = y1 + iy*tileNormY;
                line(app.UIAxes, [x1 x2], [yv yv], 'LineStyle','--', 'Tag','PreviewLine', ...
                    'HitTest','off','PickableParts','none');
            end
            hold(app.UIAxes,'off')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Dynamic preview as user types in "Tile size (°)"
            app.TileDegField.ValueChangingFcn = @(src,ev) app.updateSubdivisionPreview(ev.Value, []);
            app.TileDegField.ValueChangedFcn = @(src,ev) app.updateSubdivisionPreview();

            app.ViewingDistanceField.ValueChangingFcn = @(src,ev) app.updateSubdivisionPreview([], ev.Value);
            app.ViewingDistanceField.ValueChangedFcn = @(src,ev) app.updateSubdivisionPreview();

            % Set app window position
            scr = get(0, 'ScreenSize');

            figPos = app.RFStimulusMapper.Position;
            figW   = figPos(3);
            figH   = figPos(4);

            newX = (scr(3) - figW)/2;
            newY = (scr(4) - figH)/2;
            app.RFStimulusMapper.Position = [newX, newY, figW, figH];

            % Store full‐screen resolution (pixels)
            app.screenXpx = scr(3);
            app.screenYpx = scr(4);

            defaultID = max(Screen('Screens')); % returns 0 on single-monitor mac
            app.ScreenNumberField.Value = num2str(defaultID);

            % Finish drawing then preview
            drawnow
            app.updateSubdivisionPreview();

            % Auto‐load default movie
            try
                app.loadDefaultMovie();
                app.MovieLabel.Text      = '✔';
                app.MovieLabel.FontColor = [0 .6 0];   % green
            catch ME
                warning('RFStim:autoLoadMovieFailed', ...
                    'Auto-load movie failed: %s', ME.message);
                app.MovieLabel.Text      = '✖';
                app.MovieLabel.FontColor = [1 0 0];    % red
            end

            % Auto‐load default gamma
            try
                app.loadDefaultGamma();
                app.GammaLabel.Text      = '✔';
                app.GammaLabel.FontColor = [0 .6 0];   % green
            catch ME
                warning('RFStim:autoLoadGammaFailed', ...
                    'Auto-load gamma failed: %s', ME.message);
                app.GammaLabel.Text      = '✖';
                app.GammaLabel.FontColor = [1 0 0];    % red
            end
        end

        % Button pushed function: LoadMovieButton
        function LoadMovieButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','Select movie .mat file');
            if isequal(file,0)
                return; % leave whatever status is already shown
            end
            try
                app.loadMovieFromFile(fullfile(path,file));
                app.MovieLabel.Text      = '✔';
                app.MovieLabel.FontColor = [0 .6 0];
            catch ME
                uialert(app.RFStimulusMapper, ME.message, 'Load error');
                app.MovieLabel.Text      = '✖';
                app.MovieLabel.FontColor = [1 0 0];
            end
        end

        % Button pushed function: LoadGammaButton
        function LoadGammaButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','Select gamma .mat file');
            if isequal(file,0)
                return; % leave whatever status is already shown
            end
            try
                app.loadGammaFromFile(fullfile(path,file));
                app.GammaLabel.Text      = '✔';
                app.GammaLabel.FontColor = [0 .6 0];
            catch ME
                uialert(app.RFStimulusMapper, ME.message, 'Load error');
                app.GammaLabel.Text      = '✖';
                app.GammaLabel.FontColor = [1 0 0];
            end
        end

        % Selection changed function: GridButtonGroup
        function GridButtonGroupSelectionChanged(app, event)
            app.drawPatches();
            app.updateSubdivisionPreview();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Make sure movie is loaded
            if isempty(app.movData)
                uialert(app.RFStimulusMapper, 'Failed to load RF movie.', 'Error');
                return;
            end

            % Make sure gamma table is loaded (auto-load default if needed)
            if isempty(app.gammaTable)
                try
                    app.loadDefaultGamma();
                catch ME
                    uialert(app.RFStimulusMapper, ...
                        ['Failed to load gamma table: ' ME.message], ...
                        'Error');
                    return;
                end
            end

            % Read screen number from the UI
            screenNum = str2double(app.ScreenNumberField.Value);

            if isnan(screenNum) || screenNum<0 || screenNum~=floor(screenNum)
                uialert(app.RFStimulusMapper, ...
                    'Screen ID must be a non-negative integer (0,1,2,…).', 'Error');
                return;
            end

            % Read & validate numeric inputs (now in degrees + cm)
            tileDeg = str2double(app.TileDegField.Value);
            D       = str2double(app.ViewingDistanceField.Value);
            initGray = str2double(app.DurInitGrayField.Value);
            nCycles  = str2double(app.NCycleField.Value);
            isi      = str2double(app.ISIField.Value);
            vals     = [tileDeg, D, initGray, nCycles, isi];

            if any(isnan([tileDeg, D, initGray, nCycles, isi])) || any([tileDeg, D, initGray, nCycles, isi] <= 0)
                uialert(app.RFStimulusMapper, 'Enter valid positive numbers.', 'Error');
                return;
            end

            % Build regionOpt
            if app.RadioButtonFullscreen.Value
                regionOpt = 'fullscreen';
            elseif isempty(app.selectedRegions)
                regionOpt = 'full';
            else
                regionOpt = strjoin(app.selectedRegions, '+');
            end

            % Launch
            try
                playRFStim(app.movData, ...
                    tileDeg, initGray, nCycles, isi, ...
                    'screenNumber',       screenNum, ...
                    'regionOpt',          regionOpt, ...
                    'gammaTable',         app.gammaTable, ...
                    'viewingDistanceCm',  D);
            catch ME
                uialert(app.RFStimulusMapper, ME.message, 'Error running RF Mapper');
            end
        end

        % Value changed function: TileDegField
        function TileDegFieldValueChanged(app, event)
            app.updateSubdivisionPreview()
        end

        % Value changed function: ViewingDistanceField
        function ViewingDistanceFieldValueChanged(app, event)
            app.updateSubdivisionPreview()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RFStimulusMapper = uifigure('Visible', 'off');
            app.RFStimulusMapper.Name = 'RF Stimulus Mapper';
            app.GridLayout = uigridlayout(app.RFStimulusMapper);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LoadMovieButton = uibutton(app.GridLayout, 'push');
            app.LoadMovieButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMovieButtonPushed, true);
            app.LoadMovieButton.Text = 'Load movData';
            app.MovieLabel = uilabel(app.GridLayout);
            app.MovieLabel.Text = '';
            app.LoadGammaButton = uibutton(app.GridLayout, 'push');
            app.LoadGammaButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGammaButtonPushed, true);
            app.LoadGammaButton.Text = 'Load Gamma';
            app.GammaLabel = uilabel(app.GridLayout);
            app.GammaLabel.Text = '';
            app.DurInitGrayLabel = uilabel(app.GridLayout);
            app.DurInitGrayLabel.Text = 'Init gray (s):';
            app.DurInitGrayField = uieditfield(app.GridLayout, 'text');
            app.DurInitGrayField.InputType = 'digits';
            app.DurInitGrayField.Value = '1';
            app.NCycleLabel = uilabel(app.GridLayout);
            app.NCycleLabel.Text = 'Cycles:';
            app.NCycleField = uieditfield(app.GridLayout, 'text');
            app.NCycleField.InputType = 'digits';
            app.NCycleField.Value = '1';
            app.ISILabel = uilabel(app.GridLayout);
            app.ISILabel.Text = 'ISI (s):';
            app.ISIField = uieditfield(app.GridLayout, 'text');
            app.ISIField.InputType = 'digits';
            app.ISIField.Value = '2';
            app.TileDegLabel = uilabel(app.GridLayout);
            app.TileDegLabel.Text = 'Tile size (°)';
            app.TileDegField = uieditfield(app.GridLayout, 'text');
            app.TileDegField.InputType = 'digits';
            app.TileDegField.ValueChangedFcn = createCallbackFcn(app, @TileDegFieldValueChanged, true);
            app.TileDegField.Value = '20';
            app.ViewingDistanceLabel = uilabel(app.GridLayout);
            app.ViewingDistanceLabel.Text = 'View dist (cm)';
            app.ViewingDistanceField = uieditfield(app.GridLayout, 'text');
            app.ViewingDistanceField.InputType = 'digits';
            app.ViewingDistanceField.ValueChangedFcn = createCallbackFcn(app, @ViewingDistanceFieldValueChanged, true);
            app.ViewingDistanceField.Value = '20';
            app.GridButtonGroup = uibuttongroup(app.GridLayout);
            app.GridButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GridButtonGroupSelectionChanged, true);
            app.RadioButton3x3 = uiradiobutton(app.GridButtonGroup);
            app.RadioButton3x3.Text = '3x3';
            app.RadioButton3x3.Value = true;
            app.RadioButton2x2 = uiradiobutton(app.GridButtonGroup);
            app.RadioButton2x2.Text = '2x2';
            app.RadioButtonFullscreen = uiradiobutton(app.GridButtonGroup);
            app.RadioButtonFullscreen.Text = 'Full';
            app.ScreenNumberLabel = uilabel(app.GridLayout);
            app.ScreenNumberLabel.Text = 'Screen';
            app.ScreenNumberField = uieditfield(app.GridLayout, 'text');
            app.ScreenNumberField.InputType = 'digits';
            app.ScreenNumberField.Value = '1';
            % Show the figure after all components are created
            app.RFStimulusMapper.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RFStimGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RFStimulusMapper)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RFStimulusMapper)
        end
    end
end